classdef dset_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OverwriteCheckBox               matlab.ui.control.CheckBox
        anchordsetSpinner               matlab.ui.control.Spinner
        anchordsetSpinnerLabel          matlab.ui.control.Label
        SavematdataButton               matlab.ui.control.Button
        LoadmatdataButton               matlab.ui.control.Button
        matdatapathEditField            matlab.ui.control.EditField
        matdatapathEditFieldLabel       matlab.ui.control.Label
        RunallDropDown                  matlab.ui.control.DropDown
        RunallDropDownLabel             matlab.ui.control.Label
        RunallButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        singlecellTab                   matlab.ui.container.Tab
        poolregionsCheckBox             matlab.ui.control.CheckBox
        UseregdatalabelsCheckBox        matlab.ui.control.CheckBox
        RespEditField                   matlab.ui.control.NumericEditField
        RespEditFieldLabel              matlab.ui.control.Label
        BaselineEditField               matlab.ui.control.NumericEditField
        BaselineEditFieldLabel          matlab.ui.control.Label
        ColoredbytimeCheckBox           matlab.ui.control.CheckBox
        IndividualtrialsCheckBox        matlab.ui.control.CheckBox
        ConverttoZCheckBox              matlab.ui.control.CheckBox
        regiontoplotDropDown            matlab.ui.control.DropDown
        regiontoplotDropDownLabel       matlab.ui.control.Label
        plottypeDropDown                matlab.ui.control.DropDown
        plottypeDropDownLabel           matlab.ui.control.Label
        plotfeatureDropDown             matlab.ui.control.DropDown
        plotfeatureDropDownLabel        matlab.ui.control.Label
        numfreqSpinner                  matlab.ui.control.Spinner
        numfreqSpinnerLabel             matlab.ui.control.Label
        SizefactorEditField             matlab.ui.control.NumericEditField
        SizefactorEditFieldLabel        matlab.ui.control.Label
        SizemagadjustCheckBox           matlab.ui.control.CheckBox
        RespthreshEditField             matlab.ui.control.NumericEditField
        RespthreshEditFieldLabel        matlab.ui.control.Label
        PlotregButton                   matlab.ui.control.Button
        FramerateEditField              matlab.ui.control.NumericEditField
        FramerateEditFieldLabel         matlab.ui.control.Label
        MMNfreqEditField                matlab.ui.control.EditField
        MMNfreqEditFieldLabel           matlab.ui.control.Label
        SNREditField                    matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        MarginalizedistCheckBox         matlab.ui.control.CheckBox
        plotMMNButton                   matlab.ui.control.Button
        plotdistButton                  matlab.ui.control.Button
        poolgroupsCheckBox              matlab.ui.control.CheckBox
        SplitonoffCheckBox              matlab.ui.control.CheckBox
        PlottuningdistButton            matlab.ui.control.Button
        PlotselecttrialButton           matlab.ui.control.Button
        NewplotsCheckBox                matlab.ui.control.CheckBox
        ManualtimewinCheckBox           matlab.ui.control.CheckBox
        FullredCheckBox                 matlab.ui.control.CheckBox
        PlotctxrespButton               matlab.ui.control.Button
        PlotfreqrespButton              matlab.ui.control.Button
        ensembleTab                     matlab.ui.container.Tab
        enslesscorrButton               matlab.ui.control.Button
        Enslesssingletrial2Button       matlab.ui.control.Button
        plotraster2Button               matlab.ui.control.Button
        clusttriggeredwhatDropDown      matlab.ui.control.DropDown
        clusttriggeredwhatDropDownLabel  matlab.ui.control.Label
        ClustertouseEditField           matlab.ui.control.NumericEditField
        ClustertouseEditFieldLabel      matlab.ui.control.Label
        plotclusttriggeredstuffCheckBox  matlab.ui.control.CheckBox
        NumclustEditField               matlab.ui.control.NumericEditField
        NumclustEditFieldLabel          matlab.ui.control.Label
        HclusterCheckBox                matlab.ui.control.CheckBox
        PlotrespensCheckBox             matlab.ui.control.CheckBox
        PlotcombinedtrialsCheckBox      matlab.ui.control.CheckBox
        PlotcorrtotalsCheckBox          matlab.ui.control.CheckBox
        SelectcellsDropDown             matlab.ui.control.DropDown
        SelectcellsDropDownLabel        matlab.ui.control.Label
        ShufletrorderinsteadCheckBox    matlab.ui.control.CheckBox
        PlotindivtrialsCheckBox         matlab.ui.control.CheckBox
        EnslesstrialdimButton           matlab.ui.control.Button
        sortwithfullfrCheckBox          matlab.ui.control.CheckBox
        sorttrialsCheckBox              matlab.ui.control.CheckBox
        resortbyensCheckBox             matlab.ui.control.CheckBox
        selectrespcellsCheckBox         matlab.ui.control.CheckBox
        enslesssingletrialButton        matlab.ui.control.Button
        plotensrasterButton             matlab.ui.control.Button
        SortenstrialsCheckBox           matlab.ui.control.CheckBox
        plotscoresrasterButton          matlab.ui.control.Button
        plotensdeetsButton              matlab.ui.control.Button
        plotenscountsButton             matlab.ui.control.Button
        numsigensEditField              matlab.ui.control.NumericEditField
        numsigensEditFieldLabel         matlab.ui.control.Label
        numenstofindEditField           matlab.ui.control.NumericEditField
        numenstofindEditFieldLabel      matlab.ui.control.Label
        DimCVEditField                  matlab.ui.control.NumericEditField
        DimCVEditFieldLabel             matlab.ui.control.Label
        DimpcaEditField                 matlab.ui.control.NumericEditField
        DimpcaEditFieldLabel            matlab.ui.control.Label
        EstimatedimCVButton             matlab.ui.control.Button
        EstimatedimpcaButton            matlab.ui.control.Button
        isomapButton                    matlab.ui.control.Button
        ensembleanalysisButton          matlab.ui.control.Button
        plotsortingstuffCheckBox        matlab.ui.control.CheckBox
        sortbytrialtypeCheckBox         matlab.ui.control.CheckBox
        sortbycellsimilarityCheckBox    matlab.ui.control.CheckBox
        shuffletrialsCheckBox           matlab.ui.control.CheckBox
        shufflecellsCheckBox            matlab.ui.control.CheckBox
        plotrasterButton                matlab.ui.control.Button
        statsTab                        matlab.ui.container.Tab
        NewstatsparamsPanel             matlab.ui.container.Panel
        newLocothreshEditField          matlab.ui.control.NumericEditField
        LocothreshEditFieldLabel_2      matlab.ui.control.Label
        recomputestatsButton            matlab.ui.control.Button
        newrespwinEditField             matlab.ui.control.NumericEditField
        respwinEditFieldLabel_2         matlab.ui.control.Label
        newNumshuffsampEditField        matlab.ui.control.NumericEditField
        NumshuffsampEditFieldLabel      matlab.ui.control.Label
        newPeakbintimesecEditField      matlab.ui.control.NumericEditField
        PeakbintimesecEditFieldLabel_2  matlab.ui.control.Label
        newPvalEditField                matlab.ui.control.NumericEditField
        PvalEditFieldLabel_2            matlab.ui.control.Label
        newZthreshEditField             matlab.ui.control.NumericEditField
        ZthreshEditFieldLabel_2         matlab.ui.control.Label
        newStatsourceDropDown           matlab.ui.control.DropDown
        StatsourceDropDownLabel         matlab.ui.control.Label
        newStatmethodDropDown           matlab.ui.control.DropDown
        StatmethodDropDownLabel         matlab.ui.control.Label
        newbasewinEditField             matlab.ui.control.NumericEditField
        basewinEditFieldLabel_2         matlab.ui.control.Label
        CurrentstatsparamsPanel         matlab.ui.container.Panel
        currentLocothreshEditField      matlab.ui.control.NumericEditField
        LocothreshEditFieldLabel        matlab.ui.control.Label
        currentstatssourceEditField     matlab.ui.control.EditField
        StatssourceLabel                matlab.ui.control.Label
        currentrespwinEditField         matlab.ui.control.NumericEditField
        respwinEditFieldLabel           matlab.ui.control.Label
        currentbasewinEditField         matlab.ui.control.NumericEditField
        basewinEditFieldLabel           matlab.ui.control.Label
        currentNumshuffsampEditField    matlab.ui.control.NumericEditField
        NumshuffsampLabel               matlab.ui.control.Label
        currentPeakbintimesecEditField  matlab.ui.control.NumericEditField
        PeakbintimesecEditFieldLabel    matlab.ui.control.Label
        currentstatsmethodEditField     matlab.ui.control.EditField
        StatsmethodLabel                matlab.ui.control.Label
        currentPvalEditField            matlab.ui.control.NumericEditField
        PvalEditFieldLabel              matlab.ui.control.Label
        currentZthreshEditField         matlab.ui.control.NumericEditField
        ZthreshEditFieldLabel           matlab.ui.control.Label
        computecdataButton              matlab.ui.control.Button
        RectifyspikesCheckBox           matlab.ui.control.CheckBox
        SubtractmeanspikesCheckBox      matlab.ui.control.CheckBox
        NormalizemaxspikesCheckBox      matlab.ui.control.CheckBox
        SelectdatagroupButtonGroup      matlab.ui.container.ButtonGroup
        planeButton                     matlab.ui.control.ToggleButton
        allButton_2                     matlab.ui.control.ToggleButton
        regionButton                    matlab.ui.control.ToggleButton
        mouseButton                     matlab.ui.control.ToggleButton
        dsetButton                      matlab.ui.control.ToggleButton
        UpdatefigsCheckBox              matlab.ui.control.CheckBox
        SmoothsigmamsEditField          matlab.ui.control.NumericEditField
        SmoothsigmamsEditFieldLabel     matlab.ui.control.Label
        SmoothCheckBox                  matlab.ui.control.CheckBox
        DeconvolutionmethodDropDown     matlab.ui.control.DropDown
        DeconvolutionmethodDropDownLabel  matlab.ui.control.Label
        CButton                         matlab.ui.control.StateButton
        UpdateButton                    matlab.ui.control.Button
        ShiftEditField_st               matlab.ui.control.NumericEditField
        ShiftEditField_3Label           matlab.ui.control.Label
        ScaleEditField_st               matlab.ui.control.NumericEditField
        ScaleEditField_3Label           matlab.ui.control.Label
        ShiftEditField_sp               matlab.ui.control.NumericEditField
        ShiftEditFieldLabel             matlab.ui.control.Label
        ScaleEditField_sp               matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        trialtypeDropDown               matlab.ui.control.DropDown
        trialtypeDropDownLabel          matlab.ui.control.Label
        StimtimesButton                 matlab.ui.control.StateButton
        LoadregdataButton               matlab.ui.control.Button
        regdatapathEditField            matlab.ui.control.EditField
        regdatapathEditFieldLabel       matlab.ui.control.Label
        SpikesButton                    matlab.ui.control.StateButton
        RawButton                       matlab.ui.control.StateButton
        CellSpinner                     matlab.ui.control.Spinner
        CellSpinnerLabel                matlab.ui.control.Label
        mplSpinner                      matlab.ui.control.Spinner
        mplSpinnerLabel                 matlab.ui.control.Label
        DatasetDropDown                 matlab.ui.control.DropDown
        DatasetDropDownLabel            matlab.ui.control.Label
        Runs31Button                    matlab.ui.control.Button
        ContourlimitsLabel              matlab.ui.control.Label
        ContourMaxEditField             matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        ContourMinEditField             matlab.ui.control.NumericEditField
        MinLabel                        matlab.ui.control.Label
        ContoursButtonGroup             matlab.ui.container.ButtonGroup
        LocomotionButton                matlab.ui.control.ToggleButton
        SNRButton_5                     matlab.ui.control.ToggleButton
        OnsetoffsetButton               matlab.ui.control.ToggleButton
        TuningmagButton                 matlab.ui.control.ToggleButton
        TuningtypeButton                matlab.ui.control.ToggleButton
        NoneButton                      matlab.ui.control.ToggleButton
        UIAxesColorPallet               matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        gui_ops= struct;
        data;
        ops;
        ddata;
        cdata;
        reg_data;
        working_ops;
        gui_plots;
        current_data_idx;
        current_cell_raw;
        current_cell_C;
        current_cell_spikes;
        border_coords;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gui_ops.gui_dir = fileparts(which('dset_viewer.mlapp'));
            addpath(app.gui_ops.gui_dir);
            addpath([app.gui_ops.gui_dir '\gui_functions']);
            addpath([app.gui_ops.gui_dir '\general_functions']);
            addpath([app.gui_ops.gui_dir '\s3_mpl_functions']);
            f_dset_viewer_ops(app);
            f_dv_initialize(app);
        end

        % Button pushed function: Runs31Button
        function Runs31ButtonPushed(app, event)
            f_dv_runs31_button(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            f_dv_random_button(app);
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            f_dv_load_dset_from_data(app);
        end

        % Value changed function: mplSpinner
        function mplSpinnerValueChanged(app, event)
            f_dv_update_dset_info(app);
            f_dv_update_A(app);
            f_dv_update_cell(app);
            f_dv_initialize_contours(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: CellSpinner
        function CellSpinnerValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Button pushed function: LoadregdataButton
        function LoadregdataButtonPushed(app, event)
            f_dv_load_reg_data(app);
        end

        % Value changed function: RawButton
        function RawButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: CButton
        function CButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: SpikesButton
        function SpikesButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: StimtimesButton
        function StimtimesButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: trialtypeDropDown
        function trialtypeDropDownValueChanged(app, event)
            f_dv_update_cell(app);
            if app.UpdatefigsCheckBox.Value
                if isgraphics(app.gui_plots.select_resp_fig)
                    f_dv_plot_select_trial(app)
                end
            end
            f_dv_set_contorus(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            f_dv_update_cell(app);
            f_dv_set_contorus(app)
        end

        % Button pushed function: PlotfreqrespButton
        function PlotfreqrespButtonPushed(app, event)
            f_dv_plot_freq_resp(app);
        end

        % Button pushed function: PlotctxrespButton
        function PlotctxrespButtonPushed(app, event)
            f_dv_plot_ctx_resp(app);
        end

        % Button pushed function: PlotselecttrialButton
        function PlotselecttrialButtonPushed(app, event)
            f_dv_plot_select_trial(app);
        end

        % Value changed function: SmoothCheckBox
        function SmoothCheckBoxValueChanged(app, event)
            f_dv_update_current_cdata(app);
            f_dv_update_cell(app);
        end

        % Value changed function: DeconvolutionmethodDropDown
        function DeconvolutionmethodDropDownValueChanged(app, event)
            f_dv_update_current_cdata(app);
            f_dv_update_cell(app);
        end

        % Callback function
        function baserespsEditFieldValueChanged(app, event)
            f_dv_load_ops(app);
            f_dv_update_dset_info(app); 
        end

        % Value changed function: newStatsourceDropDown
        function newStatsourceDropDownValueChanged(app, event)
            f_dv_update_dset_info(app); 
        end

        % Value changed function: newStatmethodDropDown
        function newStatmethodDropDownValueChanged(app, event)
            f_dv_update_dset_info(app);
        end

        % Button pushed function: recomputestatsButton
        function recomputestatsButtonPushed(app, event)
            f_dv_compute_stats(app);
        end

        % Selection changed function: ContoursButtonGroup
        function ContoursButtonGroupSelectionChanged(app, event)
            f_dv_set_contorus(app);
        end

        % Value changed function: ContourMinEditField
        function ContourMinEditFieldValueChanged(app, event)
            temp_val = app.ContourMinEditField.Value;
            if temp_val < app.gui_ops.contour_params.c_abs_lim(1)
                app.ContourMinEditField.Value = app.gui_ops.contour_params.c_abs_lim(1);
            elseif temp_val > app.ContourMaxEditField.Value
                app.ContourMinEditField.Value = app.ContourMaxEditField.Value;
            end
            app.gui_ops.contour_params.c_lim(1) = app.ContourMinEditField.Value;
            f_dv_update_contour_lims(app)
            f_dv_set_contorus(app)
        end

        % Value changed function: ContourMaxEditField
        function ContourMaxEditFieldValueChanged(app, event)
            temp_val = app.ContourMaxEditField.Value;
            if temp_val > app.gui_ops.contour_params.c_abs_lim(2)
                app.ContourMaxEditField.Value = app.gui_ops.contour_params.c_abs_lim(2);
            elseif temp_val < app.ContourMinEditField.Value
                app.ContourMaxEditField.Value = app.ContourMinEditField.Value;
            end
            app.gui_ops.contour_params.c_lim(2) = app.ContourMaxEditField.Value;
            f_dv_update_contour_lims(app)
            f_dv_set_contorus(app)
        end

        % Value changed function: RectifyspikesCheckBox
        function RectifyspikesCheckBoxValueChanged(app, event)
            f_dv_update_current_cdata(app);
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: SubtractmeanspikesCheckBox
        function SubtractmeanspikesCheckBoxValueChanged(app, event)
            f_dv_update_current_cdata(app);
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: NormalizemaxspikesCheckBox
        function NormalizemaxspikesCheckBoxValueChanged(app, event)
            f_dv_update_current_cdata(app);
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: ConverttoZCheckBox
        function ConverttoZCheckBoxValueChanged(app, event)
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Button pushed function: PlotregButton
        function PlotregButtonPushed(app, event)
            f_dv_plot_reg(app);
        end

        % Button pushed function: PlottuningdistButton
        function PlottuningdistButtonPushed(app, event)
            f_dv_plot_tuning(app);
        end

        % Value changed function: numfreqSpinner
        function numfreqSpinnerValueChanged(app, event)
            f_dv_plot_reg(app);
        end

        % Button pushed function: plotdistButton
        function plotdistButtonPushed(app, event)
            f_dv_plot_dist(app);
        end

        % Button pushed function: plotMMNButton
        function plotMMNButtonPushed(app, event)
            f_dv_plot_mmn(app);
        end

        % Button pushed function: plotrasterButton
        function plotrasterButtonPushed(app, event)
            f_dv_plot_raster(app);
        end

        % Button pushed function: ensembleanalysisButton
        function ensembleanalysisButtonPushed(app, event)
            f_dv_ensemble_analysis(app);
        end

        % Button pushed function: isomapButton
        function isomapButtonPushed(app, event)
            f_dv_isomap(app);
        end

        % Button pushed function: EstimatedimpcaButton
        function EstimatedimpcaButtonPushed(app, event)
            f_dv_estimate_dim_pca(app);
        end

        % Button pushed function: EstimatedimCVButton
        function EstimatedimCVButtonPushed(app, event)
            f_dv_estimate_dim_cv(app);
        end

        % Button pushed function: RunallButton
        function RunallButtonPushed(app, event)
            f_dv_run_all(app);
        end

        % Button pushed function: LoadmatdataButton
        function LoadmatdataButtonPushed(app, event)
            f_dv_load_mat_data(app);
        end

        % Button pushed function: SavematdataButton
        function SavematdataButtonPushed(app, event)
            f_dv_save_mat_data(app);
        end

        % Button pushed function: computecdataButton
        function computecdataButtonPushed(app, event)
            f_dv_compute_cdata_all(app);
        end

        % Button pushed function: plotenscountsButton
        function plotenscountsButtonPushed(app, event)
            f_dv_plot_ens_counts(app);
        end

        % Button pushed function: plotensdeetsButton
        function plotensdeetsButtonPushed(app, event)
            f_dv_plot_ens_deets(app);
        end

        % Button pushed function: plotscoresrasterButton
        function plotscoresrasterButtonPushed(app, event)
            f_dv_plot_scores_raster(app);
        end

        % Button pushed function: plotensrasterButton
        function plotensrasterButtonPushed(app, event)
            f_dv_plot_ens_raster(app);
        end

        % Button pushed function: enslesssingletrialButton
        function enslesssingletrialButtonPushed(app, event)
            f_dv_ensless_single_trial(app);
        end

        % Button pushed function: EnslesstrialdimButton
        function EnslesstrialdimButtonPushed(app, event)
            f_dv_ensless_trial_dim(app);
        end

        % Value changed function: newZthreshEditField
        function newZthreshEditFieldValueChanged(app, event)
            app.newPvalEditField.Value = (1-normcdf(app.newZthreshEditField.Value));
        end

        % Value changed function: newPvalEditField
        function newPvalEditFieldValueChanged(app, event)
            app.newZthreshEditField.Value = norminv(1-app.newPvalEditField.Value);
        end

        % Button pushed function: plotraster2Button
        function plotraster2ButtonPushed(app, event)
            f_dv_plot_raster2(app);
        end

        % Button pushed function: Enslesssingletrial2Button
        function Enslesssingletrial2ButtonPushed(app, event)
            f_dv_ensless_single_trial2(app);
        end

        % Button pushed function: enslesscorrButton
        function enslesscorrButtonPushed(app, event)
            f_dv_ensless_sintle_trial_corr(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'A')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Trace')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxesColorPallet = uiaxes(app.UIFigure);
            xlabel(app.UIAxesColorPallet, 'Contour color')
            app.UIAxesColorPallet.YTick = [];
            app.UIAxesColorPallet.YTickLabel = '';
            app.ContoursButtonGroup = uibuttongroup(app.UIFigure);
            app.ContoursButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ContoursButtonGroupSelectionChanged, true);
            app.ContoursButtonGroup.Title = 'Contours';
            app.NoneButton = uitogglebutton(app.ContoursButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.TuningtypeButton = uitogglebutton(app.ContoursButtonGroup);
            app.TuningtypeButton.Text = 'Tuning type';
            app.TuningmagButton = uitogglebutton(app.ContoursButtonGroup);
            app.TuningmagButton.Text = 'Tuning mag';
            app.OnsetoffsetButton = uitogglebutton(app.ContoursButtonGroup);
            app.OnsetoffsetButton.Text = 'Onset offset';
            app.SNRButton_5 = uitogglebutton(app.ContoursButtonGroup);
            app.SNRButton_5.Text = 'SNR';
            app.LocomotionButton = uitogglebutton(app.ContoursButtonGroup);
            app.LocomotionButton.Text = 'Locomotion';
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = 'Min';
            app.ContourMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContourMinEditField.ValueChangedFcn = createCallbackFcn(app, @ContourMinEditFieldValueChanged, true);
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'Max';
            app.ContourMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContourMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ContourMaxEditFieldValueChanged, true);
            app.ContourlimitsLabel = uilabel(app.UIFigure);
            app.ContourlimitsLabel.Text = 'Contour limits';
            app.Runs31Button = uibutton(app.UIFigure, 'push');
            app.Runs31Button.ButtonPushedFcn = createCallbackFcn(app, @Runs31ButtonPushed, true);
            app.Runs31Button.Text = 'Run s31';
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = {};
            app.mplSpinnerLabel = uilabel(app.UIFigure);
            app.mplSpinnerLabel.Text = 'mpl';
            app.mplSpinner = uispinner(app.UIFigure);
            app.mplSpinner.Limits = [1 Inf];
            app.mplSpinner.RoundFractionalValues = 'on';
            app.mplSpinner.ValueChangedFcn = createCallbackFcn(app, @mplSpinnerValueChanged, true);
            app.mplSpinner.Value = 1;
            app.CellSpinnerLabel = uilabel(app.UIFigure);
            app.CellSpinnerLabel.Text = 'Cell';
            app.CellSpinner = uispinner(app.UIFigure);
            app.CellSpinner.Limits = [1 Inf];
            app.CellSpinner.RoundFractionalValues = 'on';
            app.CellSpinner.ValueChangedFcn = createCallbackFcn(app, @CellSpinnerValueChanged, true);
            app.CellSpinner.Value = 1;
            app.RawButton = uibutton(app.UIFigure, 'state');
            app.RawButton.ValueChangedFcn = createCallbackFcn(app, @RawButtonValueChanged, true);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.SpikesButton = uibutton(app.UIFigure, 'state');
            app.SpikesButton.ValueChangedFcn = createCallbackFcn(app, @SpikesButtonValueChanged, true);
            app.SpikesButton.Text = 'Spikes';
            app.regdatapathEditFieldLabel = uilabel(app.UIFigure);
            app.regdatapathEditFieldLabel.Text = 'reg data path';
            app.regdatapathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadregdataButton = uibutton(app.UIFigure, 'push');
            app.LoadregdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadregdataButtonPushed, true);
            app.LoadregdataButton.Text = 'Load reg data';
            app.StimtimesButton = uibutton(app.UIFigure, 'state');
            app.StimtimesButton.ValueChangedFcn = createCallbackFcn(app, @StimtimesButtonValueChanged, true);
            app.StimtimesButton.Text = 'Stim times';
            app.trialtypeDropDownLabel = uilabel(app.UIFigure);
            app.trialtypeDropDownLabel.Text = 'trial type';
            app.trialtypeDropDown = uidropdown(app.UIFigure);
            app.trialtypeDropDown.Items = {};
            app.trialtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @trialtypeDropDownValueChanged, true);
            app.trialtypeDropDown.Value = {};
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField_sp = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField_sp.Value = 1;
            app.ShiftEditFieldLabel = uilabel(app.UIFigure);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField_sp = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField_3Label = uilabel(app.UIFigure);
            app.ScaleEditField_3Label.Text = 'Scale';
            app.ScaleEditField_st = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField_st.Value = 1;
            app.ShiftEditField_3Label = uilabel(app.UIFigure);
            app.ShiftEditField_3Label.Text = 'Shift';
            app.ShiftEditField_st = uieditfield(app.UIFigure, 'numeric');
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CButton = uibutton(app.UIFigure, 'state');
            app.CButton.ValueChangedFcn = createCallbackFcn(app, @CButtonValueChanged, true);
            app.CButton.Text = 'C';
            app.DeconvolutionmethodDropDownLabel = uilabel(app.UIFigure);
            app.DeconvolutionmethodDropDownLabel.Text = 'Deconvolution method';
            app.DeconvolutionmethodDropDown = uidropdown(app.UIFigure);
            app.DeconvolutionmethodDropDown.Items = {};
            app.DeconvolutionmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DeconvolutionmethodDropDownValueChanged, true);
            app.DeconvolutionmethodDropDown.Value = {};
            app.SmoothCheckBox = uicheckbox(app.UIFigure);
            app.SmoothCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothCheckBoxValueChanged, true);
            app.SmoothCheckBox.Text = 'Smooth';
            app.SmoothCheckBox.Value = true;
            app.SmoothsigmamsEditFieldLabel = uilabel(app.UIFigure);
            app.SmoothsigmamsEditFieldLabel.Text = 'Smooth sigma (ms)';
            app.SmoothsigmamsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SmoothsigmamsEditField.Value = 150;
            app.UpdatefigsCheckBox = uicheckbox(app.UIFigure);
            app.UpdatefigsCheckBox.Text = 'Update figs';
            app.UpdatefigsCheckBox.Value = true;
            app.SelectdatagroupButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectdatagroupButtonGroup.Title = 'Select data group';
            app.dsetButton = uitogglebutton(app.SelectdatagroupButtonGroup);
            app.dsetButton.Text = 'dset';
            app.dsetButton.Value = true;
            app.mouseButton = uitogglebutton(app.SelectdatagroupButtonGroup);
            app.mouseButton.Text = 'mouse';
            app.regionButton = uitogglebutton(app.SelectdatagroupButtonGroup);
            app.regionButton.Text = 'region';
            app.allButton_2 = uitogglebutton(app.SelectdatagroupButtonGroup);
            app.allButton_2.Text = 'all';
            app.planeButton = uitogglebutton(app.SelectdatagroupButtonGroup);
            app.planeButton.Text = 'plane';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.singlecellTab = uitab(app.TabGroup);
            app.singlecellTab.Title = 'single cell';
            app.PlotfreqrespButton = uibutton(app.singlecellTab, 'push');
            app.PlotfreqrespButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfreqrespButtonPushed, true);
            app.PlotfreqrespButton.Text = 'Plot freq resp';
            app.PlotctxrespButton = uibutton(app.singlecellTab, 'push');
            app.PlotctxrespButton.ButtonPushedFcn = createCallbackFcn(app, @PlotctxrespButtonPushed, true);
            app.PlotctxrespButton.Text = 'Plot ctx resp';
            app.FullredCheckBox = uicheckbox(app.singlecellTab);
            app.FullredCheckBox.Text = 'Full red';
            app.ManualtimewinCheckBox = uicheckbox(app.singlecellTab);
            app.ManualtimewinCheckBox.Text = 'Manual time win';
            app.NewplotsCheckBox = uicheckbox(app.singlecellTab);
            app.NewplotsCheckBox.Text = 'New plots';
            app.PlotselecttrialButton = uibutton(app.singlecellTab, 'push');
            app.PlotselecttrialButton.ButtonPushedFcn = createCallbackFcn(app, @PlotselecttrialButtonPushed, true);
            app.PlotselecttrialButton.Text = 'Plot select trial';
            app.PlottuningdistButton = uibutton(app.singlecellTab, 'push');
            app.PlottuningdistButton.ButtonPushedFcn = createCallbackFcn(app, @PlottuningdistButtonPushed, true);
            app.PlottuningdistButton.Text = 'Plot tuning dist';
            app.SplitonoffCheckBox = uicheckbox(app.singlecellTab);
            app.SplitonoffCheckBox.Text = 'Split on/off';
            app.poolgroupsCheckBox = uicheckbox(app.singlecellTab);
            app.poolgroupsCheckBox.Text = 'pool groups';
            app.poolgroupsCheckBox.Value = true;
            app.plotdistButton = uibutton(app.singlecellTab, 'push');
            app.plotdistButton.ButtonPushedFcn = createCallbackFcn(app, @plotdistButtonPushed, true);
            app.plotdistButton.Text = 'plot dist';
            app.plotMMNButton = uibutton(app.singlecellTab, 'push');
            app.plotMMNButton.ButtonPushedFcn = createCallbackFcn(app, @plotMMNButtonPushed, true);
            app.plotMMNButton.Text = 'plot MMN';
            app.MarginalizedistCheckBox = uicheckbox(app.singlecellTab);
            app.MarginalizedistCheckBox.Text = 'Marginalize dist';
            app.SNREditFieldLabel = uilabel(app.singlecellTab);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.singlecellTab, 'numeric');
            app.SNREditField.Editable = 'off';
            app.MMNfreqEditFieldLabel = uilabel(app.singlecellTab);
            app.MMNfreqEditFieldLabel.Text = 'MMN freq';
            app.MMNfreqEditField = uieditfield(app.singlecellTab, 'text');
            app.MMNfreqEditField.Editable = 'off';
            app.FramerateEditFieldLabel = uilabel(app.singlecellTab);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.FramerateEditField = uieditfield(app.singlecellTab, 'numeric');
            app.FramerateEditField.Editable = 'off';
            app.PlotregButton = uibutton(app.singlecellTab, 'push');
            app.PlotregButton.ButtonPushedFcn = createCallbackFcn(app, @PlotregButtonPushed, true);
            app.PlotregButton.Text = 'Plot reg';
            app.RespthreshEditFieldLabel = uilabel(app.singlecellTab);
            app.RespthreshEditFieldLabel.Text = 'Resp thresh';
            app.RespthreshEditField = uieditfield(app.singlecellTab, 'numeric');
            app.RespthreshEditField.Limits = [0 Inf];
            app.SizemagadjustCheckBox = uicheckbox(app.singlecellTab);
            app.SizemagadjustCheckBox.Text = 'Size mag adjust';
            app.SizefactorEditFieldLabel = uilabel(app.singlecellTab);
            app.SizefactorEditFieldLabel.Text = 'Size factor';
            app.SizefactorEditField = uieditfield(app.singlecellTab, 'numeric');
            app.SizefactorEditField.Value = 0.5;
            app.numfreqSpinnerLabel = uilabel(app.singlecellTab);
            app.numfreqSpinnerLabel.Text = 'num freq';
            app.numfreqSpinner = uispinner(app.singlecellTab);
            app.numfreqSpinner.Limits = [1 10];
            app.numfreqSpinner.RoundFractionalValues = 'on';
            app.numfreqSpinner.ValueChangedFcn = createCallbackFcn(app, @numfreqSpinnerValueChanged, true);
            app.numfreqSpinner.Value = 1;
            app.plotfeatureDropDownLabel = uilabel(app.singlecellTab);
            app.plotfeatureDropDownLabel.Text = 'plot feature';
            app.plotfeatureDropDown = uidropdown(app.singlecellTab);
            app.plotfeatureDropDown.Items = {};
            app.plotfeatureDropDown.Value = {};
            app.plottypeDropDownLabel = uilabel(app.singlecellTab);
            app.plottypeDropDownLabel.Text = 'plot type';
            app.plottypeDropDown = uidropdown(app.singlecellTab);
            app.plottypeDropDown.Items = {};
            app.plottypeDropDown.Value = {};
            app.regiontoplotDropDownLabel = uilabel(app.singlecellTab);
            app.regiontoplotDropDownLabel.Text = 'region to plot';
            app.regiontoplotDropDown = uidropdown(app.singlecellTab);
            app.regiontoplotDropDown.Items = {};
            app.regiontoplotDropDown.Value = {};
            app.ConverttoZCheckBox = uicheckbox(app.singlecellTab);
            app.ConverttoZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConverttoZCheckBoxValueChanged, true);
            app.ConverttoZCheckBox.Text = 'Convert to Z';
            app.ConverttoZCheckBox.Value = true;
            app.IndividualtrialsCheckBox = uicheckbox(app.singlecellTab);
            app.IndividualtrialsCheckBox.Text = 'Individual trials';
            app.IndividualtrialsCheckBox.Value = true;
            app.ColoredbytimeCheckBox = uicheckbox(app.singlecellTab);
            app.ColoredbytimeCheckBox.Text = 'Colored by time';
            app.BaselineEditFieldLabel = uilabel(app.singlecellTab);
            app.BaselineEditFieldLabel.Text = 'Baseline';
            app.BaselineEditField = uieditfield(app.singlecellTab, 'numeric');
            app.RespEditFieldLabel = uilabel(app.singlecellTab);
            app.RespEditFieldLabel.Text = 'Resp';
            app.RespEditField = uieditfield(app.singlecellTab, 'numeric');
            app.UseregdatalabelsCheckBox = uicheckbox(app.singlecellTab);
            app.UseregdatalabelsCheckBox.Text = 'Use reg data labels';
            app.poolregionsCheckBox = uicheckbox(app.singlecellTab);
            app.poolregionsCheckBox.Text = 'pool regions';
            app.poolregionsCheckBox.Value = true;
            app.ensembleTab = uitab(app.TabGroup);
            app.ensembleTab.Title = 'ensemble';
            app.plotrasterButton = uibutton(app.ensembleTab, 'push');
            app.plotrasterButton.ButtonPushedFcn = createCallbackFcn(app, @plotrasterButtonPushed, true);
            app.plotrasterButton.Text = 'plot raster';
            app.shufflecellsCheckBox = uicheckbox(app.ensembleTab);
            app.shufflecellsCheckBox.Text = 'shuffle cells';
            app.shufflecellsCheckBox.Value = true;
            app.shuffletrialsCheckBox = uicheckbox(app.ensembleTab);
            app.shuffletrialsCheckBox.Text = 'shuffle trials';
            app.shuffletrialsCheckBox.Value = true;
            app.sortbycellsimilarityCheckBox = uicheckbox(app.ensembleTab);
            app.sortbycellsimilarityCheckBox.Text = 'sort by cell similarity';
            app.sortbycellsimilarityCheckBox.Value = true;
            app.sortbytrialtypeCheckBox = uicheckbox(app.ensembleTab);
            app.sortbytrialtypeCheckBox.Text = 'sort by trial type';
            app.sortbytrialtypeCheckBox.Value = true;
            app.plotsortingstuffCheckBox = uicheckbox(app.ensembleTab);
            app.plotsortingstuffCheckBox.Text = 'plot sorting stuff';
            app.ensembleanalysisButton = uibutton(app.ensembleTab, 'push');
            app.ensembleanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ensembleanalysisButtonPushed, true);
            app.ensembleanalysisButton.Text = 'ensemble analysis';
            app.isomapButton = uibutton(app.ensembleTab, 'push');
            app.isomapButton.ButtonPushedFcn = createCallbackFcn(app, @isomapButtonPushed, true);
            app.isomapButton.Text = 'isomap';
            app.EstimatedimpcaButton = uibutton(app.ensembleTab, 'push');
            app.EstimatedimpcaButton.ButtonPushedFcn = createCallbackFcn(app, @EstimatedimpcaButtonPushed, true);
            app.EstimatedimpcaButton.Text = 'Estimate dim pca';
            app.EstimatedimCVButton = uibutton(app.ensembleTab, 'push');
            app.EstimatedimCVButton.ButtonPushedFcn = createCallbackFcn(app, @EstimatedimCVButtonPushed, true);
            app.EstimatedimCVButton.Text = 'Estimate dim CV';
            app.DimpcaEditFieldLabel = uilabel(app.ensembleTab);
            app.DimpcaEditFieldLabel.Text = 'Dim pca';
            app.DimpcaEditField = uieditfield(app.ensembleTab, 'numeric');
            app.DimpcaEditField.Editable = 'off';
            app.DimCVEditFieldLabel = uilabel(app.ensembleTab);
            app.DimCVEditFieldLabel.Text = 'Dim CV';
            app.DimCVEditField = uieditfield(app.ensembleTab, 'numeric');
            app.DimCVEditField.Editable = 'off';
            app.numenstofindEditFieldLabel = uilabel(app.ensembleTab);
            app.numenstofindEditFieldLabel.Text = 'num ens to find';
            app.numenstofindEditField = uieditfield(app.ensembleTab, 'numeric');
            app.numsigensEditFieldLabel = uilabel(app.ensembleTab);
            app.numsigensEditFieldLabel.Text = 'num sig ens';
            app.numsigensEditField = uieditfield(app.ensembleTab, 'numeric');
            app.numsigensEditField.Editable = 'off';
            app.plotenscountsButton = uibutton(app.ensembleTab, 'push');
            app.plotenscountsButton.ButtonPushedFcn = createCallbackFcn(app, @plotenscountsButtonPushed, true);
            app.plotenscountsButton.Text = 'plot ens counts';
            app.plotensdeetsButton = uibutton(app.ensembleTab, 'push');
            app.plotensdeetsButton.ButtonPushedFcn = createCallbackFcn(app, @plotensdeetsButtonPushed, true);
            app.plotensdeetsButton.Text = 'plot ens deets';
            app.plotscoresrasterButton = uibutton(app.ensembleTab, 'push');
            app.plotscoresrasterButton.ButtonPushedFcn = createCallbackFcn(app, @plotscoresrasterButtonPushed, true);
            app.plotscoresrasterButton.Text = 'plot scores raster';
            app.SortenstrialsCheckBox = uicheckbox(app.ensembleTab);
            app.SortenstrialsCheckBox.Text = 'Sort ens trials';
            app.plotensrasterButton = uibutton(app.ensembleTab, 'push');
            app.plotensrasterButton.ButtonPushedFcn = createCallbackFcn(app, @plotensrasterButtonPushed, true);
            app.plotensrasterButton.Text = 'plot ens raster';
            app.enslesssingletrialButton = uibutton(app.ensembleTab, 'push');
            app.enslesssingletrialButton.ButtonPushedFcn = createCallbackFcn(app, @enslesssingletrialButtonPushed, true);
            app.enslesssingletrialButton.Text = 'ensless single trial';
            app.selectrespcellsCheckBox = uicheckbox(app.ensembleTab);
            app.selectrespcellsCheckBox.Text = 'select resp cells';
            app.selectrespcellsCheckBox.Value = true;
            app.resortbyensCheckBox = uicheckbox(app.ensembleTab);
            app.resortbyensCheckBox.Text = 'resort by ens';
            app.sorttrialsCheckBox = uicheckbox(app.ensembleTab);
            app.sorttrialsCheckBox.Text = 'sort trials';
            app.sortwithfullfrCheckBox = uicheckbox(app.ensembleTab);
            app.sortwithfullfrCheckBox.Text = 'sort with full fr';
            app.sortwithfullfrCheckBox.Value = true;
            app.EnslesstrialdimButton = uibutton(app.ensembleTab, 'push');
            app.EnslesstrialdimButton.ButtonPushedFcn = createCallbackFcn(app, @EnslesstrialdimButtonPushed, true);
            app.EnslesstrialdimButton.Text = 'Ensless trial dim';
            app.PlotindivtrialsCheckBox = uicheckbox(app.ensembleTab);
            app.PlotindivtrialsCheckBox.Text = 'Plot indiv trials';
            app.ShufletrorderinsteadCheckBox = uicheckbox(app.ensembleTab);
            app.ShufletrorderinsteadCheckBox.Text = 'Shufle tr order instead';
            app.ShufletrorderinsteadCheckBox.Value = true;
            app.SelectcellsDropDownLabel = uilabel(app.ensembleTab);
            app.SelectcellsDropDownLabel.Text = 'Select cells';
            app.SelectcellsDropDown = uidropdown(app.ensembleTab);
            app.SelectcellsDropDown.Items = {'Trial responsive', 'Ensemble', 'Both', 'All cells'};
            app.SelectcellsDropDown.Value = 'Trial responsive';
            app.PlotcorrtotalsCheckBox = uicheckbox(app.ensembleTab);
            app.PlotcorrtotalsCheckBox.Text = 'Plot corr totals';
            app.PlotcombinedtrialsCheckBox = uicheckbox(app.ensembleTab);
            app.PlotcombinedtrialsCheckBox.Text = 'Plot combined trials';
            app.PlotrespensCheckBox = uicheckbox(app.ensembleTab);
            app.PlotrespensCheckBox.Text = 'Plot resp ens';
            app.HclusterCheckBox = uicheckbox(app.ensembleTab);
            app.HclusterCheckBox.Text = 'Hcluster';
            app.NumclustEditFieldLabel = uilabel(app.ensembleTab);
            app.NumclustEditFieldLabel.Text = 'Num clust';
            app.NumclustEditField = uieditfield(app.ensembleTab, 'numeric');
            app.NumclustEditField.Value = 1;
            app.plotclusttriggeredstuffCheckBox = uicheckbox(app.ensembleTab);
            app.plotclusttriggeredstuffCheckBox.Text = 'plot clust triggered stuff';
            app.ClustertouseEditFieldLabel = uilabel(app.ensembleTab);
            app.ClustertouseEditFieldLabel.Text = 'Cluster to use';
            app.ClustertouseEditField = uieditfield(app.ensembleTab, 'numeric');
            app.ClustertouseEditField.Value = 1;
            app.clusttriggeredwhatDropDownLabel = uilabel(app.ensembleTab);
            app.clusttriggeredwhatDropDownLabel.Text = 'clust triggered what';
            app.clusttriggeredwhatDropDown = uidropdown(app.ensembleTab);
            app.clusttriggeredwhatDropDown.Items = {'stim', 'ensembles', 'cells'};
            app.clusttriggeredwhatDropDown.Value = 'stim';
            app.plotraster2Button = uibutton(app.ensembleTab, 'push');
            app.plotraster2Button.ButtonPushedFcn = createCallbackFcn(app, @plotraster2ButtonPushed, true);
            app.plotraster2Button.Text = 'plot raster2';
            app.Enslesssingletrial2Button = uibutton(app.ensembleTab, 'push');
            app.Enslesssingletrial2Button.ButtonPushedFcn = createCallbackFcn(app, @Enslesssingletrial2ButtonPushed, true);
            app.Enslesssingletrial2Button.Text = 'Ensless single trial2';
            app.enslesscorrButton = uibutton(app.ensembleTab, 'push');
            app.enslesscorrButton.ButtonPushedFcn = createCallbackFcn(app, @enslesscorrButtonPushed, true);
            app.enslesscorrButton.Text = 'ensless corr';
            app.statsTab = uitab(app.TabGroup);
            app.statsTab.Title = 'stats';
            app.NormalizemaxspikesCheckBox = uicheckbox(app.statsTab);
            app.NormalizemaxspikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizemaxspikesCheckBoxValueChanged, true);
            app.NormalizemaxspikesCheckBox.Text = 'Normalize max spikes';
            app.NormalizemaxspikesCheckBox.Value = true;
            app.SubtractmeanspikesCheckBox = uicheckbox(app.statsTab);
            app.SubtractmeanspikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractmeanspikesCheckBoxValueChanged, true);
            app.SubtractmeanspikesCheckBox.Text = 'Subtract mean spikes';
            app.SubtractmeanspikesCheckBox.Value = true;
            app.RectifyspikesCheckBox = uicheckbox(app.statsTab);
            app.RectifyspikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RectifyspikesCheckBoxValueChanged, true);
            app.RectifyspikesCheckBox.Text = 'Rectify spikes';
            app.RectifyspikesCheckBox.Value = true;
            app.computecdataButton = uibutton(app.statsTab, 'push');
            app.computecdataButton.ButtonPushedFcn = createCallbackFcn(app, @computecdataButtonPushed, true);
            app.computecdataButton.Text = 'compute cdata';
            app.CurrentstatsparamsPanel = uipanel(app.statsTab);
            app.CurrentstatsparamsPanel.Title = 'Current stats params';
            app.ZthreshEditFieldLabel = uilabel(app.CurrentstatsparamsPanel);
            app.ZthreshEditFieldLabel.Text = 'Z thresh';
            app.currentZthreshEditField = uieditfield(app.CurrentstatsparamsPanel, 'numeric');
            app.currentZthreshEditField.Editable = 'off';
            app.PvalEditFieldLabel = uilabel(app.CurrentstatsparamsPanel);
            app.PvalEditFieldLabel.Text = 'P-val';
            app.currentPvalEditField = uieditfield(app.CurrentstatsparamsPanel, 'numeric');
            app.currentPvalEditField.Editable = 'off';
            app.StatsmethodLabel = uilabel(app.CurrentstatsparamsPanel);
            app.StatsmethodLabel.Text = 'Stats method';
            app.currentstatsmethodEditField = uieditfield(app.CurrentstatsparamsPanel, 'text');
            app.currentstatsmethodEditField.Editable = 'off';
            app.PeakbintimesecEditFieldLabel = uilabel(app.CurrentstatsparamsPanel);
            app.PeakbintimesecEditFieldLabel.Text = 'Peak bin time (sec)';
            app.currentPeakbintimesecEditField = uieditfield(app.CurrentstatsparamsPanel, 'numeric');
            app.currentPeakbintimesecEditField.Editable = 'off';
            app.NumshuffsampLabel = uilabel(app.CurrentstatsparamsPanel);
            app.NumshuffsampLabel.Text = 'Num shuff samp';
            app.currentNumshuffsampEditField = uieditfield(app.CurrentstatsparamsPanel, 'numeric');
            app.currentNumshuffsampEditField.Editable = 'off';
            app.basewinEditFieldLabel = uilabel(app.CurrentstatsparamsPanel);
            app.basewinEditFieldLabel.Text = 'base win';
            app.currentbasewinEditField = uieditfield(app.CurrentstatsparamsPanel, 'numeric');
            app.currentbasewinEditField.Editable = 'off';
            app.respwinEditFieldLabel = uilabel(app.CurrentstatsparamsPanel);
            app.respwinEditFieldLabel.Text = 'resp win';
            app.currentrespwinEditField = uieditfield(app.CurrentstatsparamsPanel, 'numeric');
            app.currentrespwinEditField.Editable = 'off';
            app.StatssourceLabel = uilabel(app.CurrentstatsparamsPanel);
            app.StatssourceLabel.Text = 'Stats source';
            app.currentstatssourceEditField = uieditfield(app.CurrentstatsparamsPanel, 'text');
            app.currentstatssourceEditField.Editable = 'off';
            app.LocothreshEditFieldLabel = uilabel(app.CurrentstatsparamsPanel);
            app.LocothreshEditFieldLabel.Text = 'Loco thresh';
            app.currentLocothreshEditField = uieditfield(app.CurrentstatsparamsPanel, 'numeric');
            app.currentLocothreshEditField.Editable = 'off';
            app.NewstatsparamsPanel = uipanel(app.statsTab);
            app.NewstatsparamsPanel.Title = 'New stats params';
            app.basewinEditFieldLabel_2 = uilabel(app.NewstatsparamsPanel);
            app.basewinEditFieldLabel_2.Text = 'base win';
            app.newbasewinEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.StatmethodDropDownLabel = uilabel(app.NewstatsparamsPanel);
            app.StatmethodDropDownLabel.Text = 'Stat method';
            app.newStatmethodDropDown = uidropdown(app.NewstatsparamsPanel);
            app.newStatmethodDropDown.Items = {};
            app.newStatmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @newStatmethodDropDownValueChanged, true);
            app.newStatmethodDropDown.Value = {};
            app.StatsourceDropDownLabel = uilabel(app.NewstatsparamsPanel);
            app.StatsourceDropDownLabel.Text = 'Stat source';
            app.newStatsourceDropDown = uidropdown(app.NewstatsparamsPanel);
            app.newStatsourceDropDown.Items = {};
            app.newStatsourceDropDown.ValueChangedFcn = createCallbackFcn(app, @newStatsourceDropDownValueChanged, true);
            app.newStatsourceDropDown.Value = {};
            app.ZthreshEditFieldLabel_2 = uilabel(app.NewstatsparamsPanel);
            app.ZthreshEditFieldLabel_2.Text = 'Z thresh';
            app.newZthreshEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.newZthreshEditField.ValueChangedFcn = createCallbackFcn(app, @newZthreshEditFieldValueChanged, true);
            app.newZthreshEditField.Value = 3;
            app.PvalEditFieldLabel_2 = uilabel(app.NewstatsparamsPanel);
            app.PvalEditFieldLabel_2.Text = 'P-val';
            app.newPvalEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.newPvalEditField.ValueChangedFcn = createCallbackFcn(app, @newPvalEditFieldValueChanged, true);
            app.PeakbintimesecEditFieldLabel_2 = uilabel(app.NewstatsparamsPanel);
            app.PeakbintimesecEditFieldLabel_2.Text = 'Peak bin time (sec)';
            app.newPeakbintimesecEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.NumshuffsampEditFieldLabel = uilabel(app.NewstatsparamsPanel);
            app.NumshuffsampEditFieldLabel.Text = 'Num shuff samp';
            app.newNumshuffsampEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.respwinEditFieldLabel_2 = uilabel(app.NewstatsparamsPanel);
            app.respwinEditFieldLabel_2.Text = 'resp win';
            app.newrespwinEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.recomputestatsButton = uibutton(app.NewstatsparamsPanel, 'push');
            app.recomputestatsButton.ButtonPushedFcn = createCallbackFcn(app, @recomputestatsButtonPushed, true);
            app.recomputestatsButton.Text = 'recompute stats';
            app.LocothreshEditFieldLabel_2 = uilabel(app.NewstatsparamsPanel);
            app.LocothreshEditFieldLabel_2.Text = 'Loco thresh';
            app.newLocothreshEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.RunallButton = uibutton(app.UIFigure, 'push');
            app.RunallButton.ButtonPushedFcn = createCallbackFcn(app, @RunallButtonPushed, true);
            app.RunallButton.Text = 'Run all';
            app.RunallDropDownLabel = uilabel(app.UIFigure);
            app.RunallDropDownLabel.Text = 'Run all';
            app.RunallDropDown = uidropdown(app.UIFigure);
            app.RunallDropDown.Items = {};
            app.RunallDropDown.Value = {};
            app.matdatapathEditFieldLabel = uilabel(app.UIFigure);
            app.matdatapathEditFieldLabel.Text = 'mat data path';
            app.matdatapathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadmatdataButton = uibutton(app.UIFigure, 'push');
            app.LoadmatdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmatdataButtonPushed, true);
            app.LoadmatdataButton.Text = 'Load mat data';
            app.SavematdataButton = uibutton(app.UIFigure, 'push');
            app.SavematdataButton.ButtonPushedFcn = createCallbackFcn(app, @SavematdataButtonPushed, true);
            app.SavematdataButton.Text = 'Save mat data';
            app.anchordsetSpinnerLabel = uilabel(app.UIFigure);
            app.anchordsetSpinnerLabel.Text = 'anchor dset';
            app.anchordsetSpinner = uispinner(app.UIFigure);
            app.anchordsetSpinner.Limits = [1 Inf];
            app.anchordsetSpinner.Value = 1;
            app.OverwriteCheckBox = uicheckbox(app.UIFigure);
            app.OverwriteCheckBox.Text = 'Overwrite';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dset_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end